classdef format3000 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        FileInformationTab              matlab.ui.container.Tab
        EmailEditFieldLabel             matlab.ui.control.Label
        EmailEditField                  matlab.ui.control.EditField
        CreationDateDatePickerLabel     matlab.ui.control.Label
        CreationDateDatePicker          matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        ContactEditFieldLabel           matlab.ui.control.Label
        ContactEditField                matlab.ui.control.EditField
        CountryCodeDropDownLabel        matlab.ui.control.Label
        CountryCodeDropDown             matlab.ui.control.DropDown
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeEditField_2Label            matlab.ui.control.Label
        TimeEditField_2                 matlab.ui.control.EditField
        TimeEditField_3Label            matlab.ui.control.Label
        TimeEditField_3                 matlab.ui.control.EditField
        InstitutionEditFieldLabel       matlab.ui.control.Label
        InstitutionEditField            matlab.ui.control.EditField
        Lamp_Email                      matlab.ui.control.Lamp
        Lamp_CreationDate               matlab.ui.control.Lamp
        Lamp_StartDate                  matlab.ui.control.Lamp
        Lamp_EndDate                    matlab.ui.control.Lamp
        Lamp_Institution                matlab.ui.control.Lamp
        Lamp_Contact                    matlab.ui.control.Lamp
        Lamp_CountryCode                matlab.ui.control.Lamp
        Lamp_StationCode                matlab.ui.control.Lamp
        SelectDropDownLabel             matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        StationCodeEditFieldLabel       matlab.ui.control.Label
        StationCodeEditField            matlab.ui.control.EditField
        SelectDropDown_2Label           matlab.ui.control.Label
        SelectDropDown_StationCode      matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        DataTab                         matlab.ui.container.Tab
        SelectDatetimeVectorButton      matlab.ui.control.Button
        DateTimeLabel                   matlab.ui.control.Label
        SelectLeqMeasurementsofChannel1Button  matlab.ui.control.Button
        LeqMeasurementsofChannel1Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel2Button  matlab.ui.control.Button
        LeqMeasurementsofChannel2Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel3Button  matlab.ui.control.Button
        LeqMeasurementsofChannel3Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel4Button  matlab.ui.control.Button
        LeqMeasurementsofChannel4Label  matlab.ui.control.Label
        MetadataTab                     matlab.ui.container.Tab
        CreateUUIDButton                matlab.ui.control.Button
        HydrophoneTypeDropDownLabel     matlab.ui.control.Label
        HydrophoneTypeDropDown          matlab.ui.control.DropDown
        HydrophoneSerialNumberEditFieldLabel  matlab.ui.control.Label
        HydrophoneSerialNumberEditField  matlab.ui.control.EditField
        RecorderTypeDropDownLabel       matlab.ui.control.Label
        RecorderTypeDropDown            matlab.ui.control.DropDown
        RecorderSerialNumberEditFieldLabel  matlab.ui.control.Label
        RecorderSerialNumberEditField   matlab.ui.control.EditField
        MeasurementHeightEditFieldLabel  matlab.ui.control.Label
        MeasurementHeightEditField      matlab.ui.control.EditField
        MeasurementPurposeDropDownLabel  matlab.ui.control.Label
        MeasurementPurposeDropDown      matlab.ui.control.DropDown
        MeasurementSetupDropDownLabel   matlab.ui.control.Label
        MeasurementSetupDropDown        matlab.ui.control.DropDown
        RigDesignDropDownLabel          matlab.ui.control.Label
        RigDesignDropDown               matlab.ui.control.DropDown
        FrequencyCountSpinnerLabel      matlab.ui.control.Label
        FrequencyCountSpinner           matlab.ui.control.Spinner
        FrequencyIndexEditFieldLabel    matlab.ui.control.Label
        FrequencyIndexEditField         matlab.ui.control.EditField
        FrequencyUnitDropDownLabel      matlab.ui.control.Label
        FrequencyUnitDropDown           matlab.ui.control.DropDown
        ChannelCountSpinnerLabel        matlab.ui.control.Label
        ChannelCountSpinner             matlab.ui.control.Spinner
        MeasurementTotalNumbersSpinnerLabel  matlab.ui.control.Label
        MeasurementTotalNumbersSpinner  matlab.ui.control.Spinner
        MeasurementUnitDropDownLabel    matlab.ui.control.Label
        MeasurementUnitDropDown         matlab.ui.control.DropDown
        AveragingTimeSpinnerLabel       matlab.ui.control.Label
        AveragingTimeSpinner            matlab.ui.control.Spinner
        ProcessingAlgorithmDropDownLabel  matlab.ui.control.Label
        ProcessingAlgorithmDropDown     matlab.ui.control.DropDown
        DataUUIDEditFieldLabel          matlab.ui.control.Label
        DataUUIDEditField               matlab.ui.control.EditField
        DatasetVersionSpinnerLabel      matlab.ui.control.Label
        DatasetVersionSpinner           matlab.ui.control.Spinner
        CalibrationProcedureDropDownLabel  matlab.ui.control.Label
        CalibrationProcedureDropDown    matlab.ui.control.DropDown
        CalibrationDatetimeEditFieldLabel  matlab.ui.control.Label
        CalibrationDatetimeEditField    matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        CommentsEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Email % Description
        CreationDate % Description
        StartDate
        EndDate
        Institution
        Contact
        CountryCode
        StationCode
        libpath = addpath('./libs')
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EmailEditField
        function EmailEditFieldValueChanged(app, event)
            Email = app.EmailEditField.Value;
            p = strfind(Email,'.');
            at = strfind(Email,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Email = Email;
                app.Lamp_Email.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Email.Color = 'r';
            end
        end

        % Value changed function: CreationDateDatePicker
        function CreationDateDatePickerValueChanged(app, event)
            app.CreationDate = app.CreationDateDatePicker.Value;
            app.CreationDate = char(datetime(app.CreationDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_CreationDate.Color = 'g';
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            creationtime = datetime(app.TimeEditField.Value,"Format","HH:mm");
            creaHH = hour(creationtime);
            creamm = minute(creationtime);
            if exist('app.CreationDate','var')
            app.CreationDate = datetime(app.CreationDate) + hours(creaHH) + minutes(creamm);
            app.CreationDate = char(datetime(app.CreationDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
%             disp(app.CreationDate)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.StartDate = app.StartDateDatePicker.Value;
            app.StartDate = char(datetime(app.StartDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_StartDate.Color = 'g';
        end

        % Value changed function: TimeEditField_2
        function TimeEditField_2ValueChanged(app, event)
            starttime = app.TimeEditField_2.Value;
            startHH = hour(starttime);
            startmm = minute(starttime);
            if exist('app.StartDate','var')
            app.StartDate = datetime(app.StartDate) + hours(startHH) + minutes(startmm);
            app.StartDate = char(datetime(app.StartDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.EndDate = app.EndDateDatePicker.Value;
            app.EndDate = char(datetime(app.EndDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_EndDate.Color = 'g';
        end

        % Value changed function: TimeEditField_3
        function TimeEditField_3ValueChanged(app, event)
            endtime = app.TimeEditField_2.Value;
            endHH = hour(endtime);
            endmm = minute(endtime);
            if exist('app.EndDate','var')
            app.EndDate = datetime(app.EndDate) + hours(endHH) + minutes(endmm);
            app.EndDate = char(datetime(app.EndDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: InstitutionEditField
        function InstitutionEditFieldValueChanged(app, event)
            if app.Lamp_Institution.Color == [0,1,0]
                app.Lamp_Institution.Color = 'r';
            end
            institution = app.InstitutionEditField.Value;
            %%
            clear C
            txt=textread('.\libs\RECO_Export_07-14-2021-01-14-31.csv','%s','delimiter',',');
            matchstr=institution;
            ix=contains(txt,matchstr);
            idx = find(ix==1);
            C{1}='Select ...';
            for ii=1:length(idx)
                name=txt{idx(ii)};
                C{ii+1} = name;
%                 disp(txt{idx(ii)})
            end
            app.SelectDropDown.Items = C;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            Institution = app.SelectDropDown.Value;
            txt=textread('.\libs\RECO_Export_07-14-2021-01-14-31.csv','%s','delimiter','\n');
            matchstr=Institution;
            ix=contains(txt,matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Institution with this name were found. Please try again or enter Institution code from: https://vocab.ices.dk/?ref=1398')
            else
                name = txt{idx};
                codestart = min(strfind(name,','));
                app.Institution = name(1:codestart-1);
                app.Lamp_Institution.Color = 'g';
            end  
        end

        % Value changed function: ContactEditField
        function ContactEditFieldValueChanged(app, event)
            Contact = app.ContactEditField.Value;
            p = strfind(Contact,'.');
            at = strfind(Contact,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Contact = Contact;
                app.Lamp_Contact.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Contact.Color = 'r';
            end
        end

        % Value changed function: CountryCodeDropDown
        function CountryCodeDropDownValueChanged(app, event)
            app.CountryCode = app.CountryCodeDropDown.Value;
            app.Lamp_CountryCode.Color = 'g';
        end

        % Value changed function: StationCodeEditField
        function StationCodeEditFieldValueChanged(app, event)
            if app.Lamp_StationCode.Color == [0,1,0]
                app.Lamp_StationCode.Color = 'r';
            end
            StationCode = app.StationCodeEditField.Value;
            clear C txt
            txt=textread('.\libs\StationCode.csv','%s','delimiter',',');
            matchstr=StationCode;
            ix=contains(txt,matchstr);
            idx = find(ix==1);
            C{1}='Select ...';
            for ii=1:length(idx)
                name=txt{idx(ii)};
                C{ii+1} = name;
%                 disp(txt{idx(ii)})
            end
            app.SelectDropDown_StationCode.Items = C;
        end

        % Value changed function: SelectDropDown_StationCode
        function SelectDropDown_StationCodeValueChanged(app, event)
            StationCode = app.SelectDropDown_StationCode.Value;
            txt=textread('.\libs\StationCode.csv','%s','delimiter','\n');
            matchstr=StationCode;
            ix=contains(txt,matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Station with this station code were found. Please try again or enter station code from: https://vocab.ices.dk/?ref=1399')
            else
                name = txt{idx};
                codestart = strfind(name,',');
                app.Institution = name(codestart(1)+1:codestart(2)-1);
                app.Lamp_StationCode.Color = 'g';
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileInformationTab = uitab(app.TabGroup);
            app.FileInformationTab.Title = 'File Information';
            app.EmailEditFieldLabel = uilabel(app.FileInformationTab);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.FileInformationTab, 'text');
            app.EmailEditField.ValueChangedFcn = createCallbackFcn(app, @EmailEditFieldValueChanged, true);
            app.EmailEditField.Value = 'test@test.com';
            app.CreationDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.CreationDateDatePickerLabel.Text = 'Creation Date';
            app.CreationDateDatePicker = uidatepicker(app.FileInformationTab);
            app.CreationDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @CreationDateDatePickerValueChanged, true);
            app.StartDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.FileInformationTab);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.FileInformationTab);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.ContactEditFieldLabel = uilabel(app.FileInformationTab);
            app.ContactEditFieldLabel.Text = 'Contact';
            app.ContactEditField = uieditfield(app.FileInformationTab, 'text');
            app.ContactEditField.ValueChangedFcn = createCallbackFcn(app, @ContactEditFieldValueChanged, true);
            app.ContactEditField.Value = 'test@test.com';
            app.CountryCodeDropDownLabel = uilabel(app.FileInformationTab);
            app.CountryCodeDropDownLabel.Text = 'Country Code';
            app.CountryCodeDropDown = uidropdown(app.FileInformationTab);
            app.CountryCodeDropDown.Items = {'Select ...', 'DE', 'DK', 'EE', 'FI', 'LT', 'LV', 'PL', 'RU', 'SE'};
            app.CountryCodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryCodeDropDownValueChanged, true);
            app.CountryCodeDropDown.Value = 'Select ...';
            app.TimeEditFieldLabel = uilabel(app.FileInformationTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 'HH:mm';
            app.TimeEditField_2Label = uilabel(app.FileInformationTab);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_2ValueChanged, true);
            app.TimeEditField_2.Value = 'HH:mm';
            app.TimeEditField_3Label = uilabel(app.FileInformationTab);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditField_3 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_3.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_3ValueChanged, true);
            app.TimeEditField_3.Value = 'HH:mm';
            app.InstitutionEditFieldLabel = uilabel(app.FileInformationTab);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.InstitutionEditField = uieditfield(app.FileInformationTab, 'text');
            app.InstitutionEditField.ValueChangedFcn = createCallbackFcn(app, @InstitutionEditFieldValueChanged, true);
            app.InstitutionEditField.Value = 'Search';
            app.Lamp_Email = uilamp(app.FileInformationTab);
            app.Lamp_CreationDate = uilamp(app.FileInformationTab);
            app.Lamp_StartDate = uilamp(app.FileInformationTab);
            app.Lamp_EndDate = uilamp(app.FileInformationTab);
            app.Lamp_Institution = uilamp(app.FileInformationTab);
            app.Lamp_Contact = uilamp(app.FileInformationTab);
            app.Lamp_CountryCode = uilamp(app.FileInformationTab);
            app.Lamp_StationCode = uilamp(app.FileInformationTab);
            app.SelectDropDownLabel = uilabel(app.FileInformationTab);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.FileInformationTab);
            app.SelectDropDown.Items = {'Search first ...', ''};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'Search first ...';
            app.StationCodeEditFieldLabel = uilabel(app.FileInformationTab);
            app.StationCodeEditFieldLabel.Text = 'Station Code';
            app.StationCodeEditField = uieditfield(app.FileInformationTab, 'text');
            app.StationCodeEditField.ValueChangedFcn = createCallbackFcn(app, @StationCodeEditFieldValueChanged, true);
            app.StationCodeEditField.Value = 'Search';
            app.SelectDropDown_2Label = uilabel(app.FileInformationTab);
            app.SelectDropDown_2Label.Text = 'Select';
            app.SelectDropDown_StationCode = uidropdown(app.FileInformationTab);
            app.SelectDropDown_StationCode.Items = {'Search first ...', ''};
            app.SelectDropDown_StationCode.ValueChangedFcn = createCallbackFcn(app, @SelectDropDown_StationCodeValueChanged, true);
            app.SelectDropDown_StationCode.Value = 'Search first ...';
            app.Image = uiimage(app.FileInformationTab);
            app.Image.Tooltip = {'Creator of the HDF5 file/ who holds responsibility for data QA and'; 'creation of the submited hdf5 file.'};
            app.Image.ImageSource = 'info.png';
            app.Image_2 = uiimage(app.FileInformationTab);
            app.Image_2.Tooltip = {'Date of file creation. UTC DateTime in ISO 8601 format: YYYY-MMDDThh:'; 'mm[:ss] or YYYY-MM-DD hh:mm[:ss] . Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_2.ImageSource = 'info.png';
            app.Image_3 = uiimage(app.FileInformationTab);
            app.Image_3.Tooltip = {'Measurement collection start date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_3.ImageSource = 'info.png';
            app.Image_4 = uiimage(app.FileInformationTab);
            app.Image_4.Tooltip = {'Measurement collection end date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_4.ImageSource = 'info.png';
            app.Image_5 = uiimage(app.FileInformationTab);
            app.Image_5.Tooltip = {'Institution which acquired the data. https://vocab.ices.dk/?ref=1398'};
            app.Image_5.ImageSource = 'info.png';
            app.Image_6 = uiimage(app.FileInformationTab);
            app.Image_6.Tooltip = {'Contact of all future external queries/who submits/holds'; 'responsibility for submission'};
            app.Image_6.ImageSource = 'info.png';
            app.Image_7 = uiimage(app.FileInformationTab);
            app.Image_7.Tooltip = {'https://vocab.ices.dk/?ref=337'};
            app.Image_7.ImageSource = 'info.png';
            app.Image_8 = uiimage(app.FileInformationTab);
            app.Image_8.Tooltip = {'The station code and its associated coordinates can be found in the'; 'ICES station dictionary'; ''; 'https://vocab.ices.dk/?ref=1399'; ''};
            app.Image_8.ImageSource = 'info.png';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SelectDatetimeVectorButton = uibutton(app.DataTab, 'push');
            app.SelectDatetimeVectorButton.Text = 'Select Datetime Vector';
            app.DateTimeLabel = uilabel(app.DataTab);
            app.DateTimeLabel.Text = 'DateTime';
            app.SelectLeqMeasurementsofChannel1Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel1Button.Text = 'Select LeqMeasurements of Channel 1';
            app.LeqMeasurementsofChannel1Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel1Label.Text = 'Leq Measurements of Channel 1';
            app.SelectLeqMeasurementsofChannel2Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel2Button.Text = 'Select LeqMeasurements of Channel 2';
            app.LeqMeasurementsofChannel2Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel2Label.Text = 'Leq Measurements of Channel 2';
            app.SelectLeqMeasurementsofChannel3Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel3Button.Text = 'Select LeqMeasurements of Channel 3';
            app.LeqMeasurementsofChannel3Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel3Label.Text = 'Leq Measurements of Channel 3';
            app.SelectLeqMeasurementsofChannel4Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel4Button.Text = 'Select LeqMeasurements of Channel 4';
            app.LeqMeasurementsofChannel4Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel4Label.Text = 'Leq Measurements of Channel 4';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.CreateUUIDButton = uibutton(app.MetadataTab, 'push');
            app.CreateUUIDButton.Text = 'Create UUID';
            app.HydrophoneTypeDropDownLabel = uilabel(app.MetadataTab);
            app.HydrophoneTypeDropDownLabel.Text = 'Hydrophone Type';
            app.HydrophoneTypeDropDown = uidropdown(app.MetadataTab);
            app.HydrophoneSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.HydrophoneSerialNumberEditFieldLabel.Text = 'Hydrophone Serial Number';
            app.HydrophoneSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.RecorderTypeDropDownLabel = uilabel(app.MetadataTab);
            app.RecorderTypeDropDownLabel.Text = 'Recorder Type';
            app.RecorderTypeDropDown = uidropdown(app.MetadataTab);
            app.RecorderSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.RecorderSerialNumberEditFieldLabel.Text = 'Recorder Serial Number';
            app.RecorderSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.MeasurementHeightEditFieldLabel = uilabel(app.MetadataTab);
            app.MeasurementHeightEditFieldLabel.Text = 'Measurement Height';
            app.MeasurementHeightEditField = uieditfield(app.MetadataTab, 'text');
            app.MeasurementPurposeDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementPurposeDropDownLabel.Text = 'Measurement Purpose';
            app.MeasurementPurposeDropDown = uidropdown(app.MetadataTab);
            app.MeasurementSetupDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementSetupDropDownLabel.Text = 'Measurement Setup';
            app.MeasurementSetupDropDown = uidropdown(app.MetadataTab);
            app.RigDesignDropDownLabel = uilabel(app.MetadataTab);
            app.RigDesignDropDownLabel.Text = 'Rig Design';
            app.RigDesignDropDown = uidropdown(app.MetadataTab);
            app.FrequencyCountSpinnerLabel = uilabel(app.MetadataTab);
            app.FrequencyCountSpinnerLabel.Text = 'Frequency Count';
            app.FrequencyCountSpinner = uispinner(app.MetadataTab);
            app.FrequencyIndexEditFieldLabel = uilabel(app.MetadataTab);
            app.FrequencyIndexEditFieldLabel.Text = 'Frequency Index';
            app.FrequencyIndexEditField = uieditfield(app.MetadataTab, 'text');
            app.FrequencyUnitDropDownLabel = uilabel(app.MetadataTab);
            app.FrequencyUnitDropDownLabel.Text = 'Frequency Unit';
            app.FrequencyUnitDropDown = uidropdown(app.MetadataTab);
            app.ChannelCountSpinnerLabel = uilabel(app.MetadataTab);
            app.ChannelCountSpinnerLabel.Text = 'Channel Count';
            app.ChannelCountSpinner = uispinner(app.MetadataTab);
            app.MeasurementTotalNumbersSpinnerLabel = uilabel(app.MetadataTab);
            app.MeasurementTotalNumbersSpinnerLabel.Text = 'Measurement Total Numbers';
            app.MeasurementTotalNumbersSpinner = uispinner(app.MetadataTab);
            app.MeasurementUnitDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementUnitDropDownLabel.Text = 'Measurement Unit';
            app.MeasurementUnitDropDown = uidropdown(app.MetadataTab);
            app.AveragingTimeSpinnerLabel = uilabel(app.MetadataTab);
            app.AveragingTimeSpinnerLabel.Text = 'Averaging Time';
            app.AveragingTimeSpinner = uispinner(app.MetadataTab);
            app.ProcessingAlgorithmDropDownLabel = uilabel(app.MetadataTab);
            app.ProcessingAlgorithmDropDownLabel.Text = 'Processing Algorithm';
            app.ProcessingAlgorithmDropDown = uidropdown(app.MetadataTab);
            app.DataUUIDEditFieldLabel = uilabel(app.MetadataTab);
            app.DataUUIDEditFieldLabel.Text = 'Data UUID';
            app.DataUUIDEditField = uieditfield(app.MetadataTab, 'text');
            app.DatasetVersionSpinnerLabel = uilabel(app.MetadataTab);
            app.DatasetVersionSpinnerLabel.Text = 'Dataset Version';
            app.DatasetVersionSpinner = uispinner(app.MetadataTab);
            app.CalibrationProcedureDropDownLabel = uilabel(app.MetadataTab);
            app.CalibrationProcedureDropDownLabel.Text = 'Calibration Procedure';
            app.CalibrationProcedureDropDown = uidropdown(app.MetadataTab);
            app.CalibrationDatetimeEditFieldLabel = uilabel(app.MetadataTab);
            app.CalibrationDatetimeEditFieldLabel.Text = 'Calibration Datetime';
            app.CalibrationDatetimeEditField = uieditfield(app.MetadataTab, 'text');
            app.CommentsEditFieldLabel = uilabel(app.MetadataTab);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.MetadataTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = format3000

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end